classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetviewButton               matlab.ui.control.Button
        Q_R_SButton                   matlab.ui.control.Button
        EnterButton                   matlab.ui.control.Button
        WindowstartEditFieldLabel     matlab.ui.control.Label
        WindowstartEditField          matlab.ui.control.NumericEditField
        WindowendEditFieldLabel       matlab.ui.control.Label
        WindowendEditField            matlab.ui.control.NumericEditField
        LoadSignalButton              matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        DiseasesButtonGroup           matlab.ui.container.ButtonGroup
        TachycardiaBradycardiaButton  matlab.ui.control.ToggleButton
        EctopicbeatButton             matlab.ui.control.ToggleButton
        FibrillationButton            matlab.ui.control.ToggleButton
        PleaseloadaSignalLabel        matlab.ui.control.Label
        WelcometoECGApplicationLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: EnterButton
        function Load_signal(app, event)
            
            
        end

        % Button pushed function: Q_R_SButton
        function QRS_pics(app, event)
            [file,path] = uigetfile('*.mat', 'rt');
            signal = load(fullfile(path, file));
            data = signal.ecg; % Your ecg data
            Fs = signal.Fs; % Sampling frequency
            Ts=1/Fs;
            N = size(data,2); % Data length
            time_axis = (1:N)/Fs;
            m=150;
            smw = Smw(data, Fs,N);
            [R_locs,Q_locs,S_locs] = R_Q_S_peaks(data,smw,m);
            plot(app.UIAxes,data);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,Q_locs,data(Q_locs),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,S_locs,data(S_locs),'*');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,R_locs,data(R_locs),'o');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,smw);
            zoom(app.UIAxes,'on');
        end

        % Button pushed function: LoadSignalButton
        function LoadSignalButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'rt');
            signal = load(fullfile(path, file));
            data = signal.ecg; % Your ecg data
            Fs = signal.Fs; % Sampling frequency
            Ts=1/Fs;
            N = size(data,2); % Data length
            time_axis = (1:N)/Fs;
            plot(app.UIAxes,data);
            zoom(app.UIAxes,'on');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ResetviewButton = uibutton(app.UIFigure, 'push');
            app.ResetviewButton.Text = 'Reset view';
            app.Q_R_SButton = uibutton(app.UIFigure, 'push');
            app.Q_R_SButton.ButtonPushedFcn = createCallbackFcn(app, @QRS_pics, true);
            app.Q_R_SButton.Text = 'Q_R_S';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @Load_signal, true);
            app.EnterButton.Text = 'Enter';
            app.WindowstartEditFieldLabel = uilabel(app.UIFigure);
            app.WindowstartEditFieldLabel.Text = 'Window start';
            app.WindowstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowendEditFieldLabel = uilabel(app.UIFigure);
            app.WindowendEditFieldLabel.Text = 'Window end';
            app.WindowendEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadSignalButton = uibutton(app.UIFigure, 'push');
            app.LoadSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalButtonPushed, true);
            app.LoadSignalButton.Text = 'Load Signal';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ElectroCardioGram Signal')
            xlabel(app.UIAxes, 'Time axis')
            ylabel(app.UIAxes, 'Signal')
            app.DiseasesButtonGroup = uibuttongroup(app.UIFigure);
            app.DiseasesButtonGroup.Title = 'Diseases';
            app.TachycardiaBradycardiaButton = uitogglebutton(app.DiseasesButtonGroup);
            app.TachycardiaBradycardiaButton.Text = 'Tachycardia/Bradycardia';
            app.TachycardiaBradycardiaButton.Value = true;
            app.EctopicbeatButton = uitogglebutton(app.DiseasesButtonGroup);
            app.EctopicbeatButton.Text = 'Ectopic beat';
            app.FibrillationButton = uitogglebutton(app.DiseasesButtonGroup);
            app.FibrillationButton.Text = 'Fibrillation';
            app.PleaseloadaSignalLabel = uilabel(app.UIFigure);
            app.PleaseloadaSignalLabel.Text = 'Please load a Signal';
            app.WelcometoECGApplicationLabel = uilabel(app.UIFigure);
            app.WelcometoECGApplicationLabel.Text = 'Welcome to ECG Application';
        end
    end

    methods (Access = public)

        % Construct app
        function app = application

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end